classdef covid19nlsigApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLSIGCOVID19LABAPPUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ConsolePanel                   matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        StatusLabel                    matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        OptionsTab                     matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        SearchCodesDropDown            matlab.ui.control.DropDown
        SearchCodesDropDownLabel       matlab.ui.control.Label
        EpidemicGroupButtonGroup       matlab.ui.container.ButtonGroup
        InfectionsButton               matlab.ui.control.RadioButton
        DeathsButton                   matlab.ui.control.RadioButton
        GridLayout8                    matlab.ui.container.GridLayout
        UpdateDropDownLabel            matlab.ui.control.Label
        UpdateDropDown                 matlab.ui.control.DropDown
        GridLayout9                    matlab.ui.container.GridLayout
        FinerCheckBox                  matlab.ui.control.CheckBox
        ConfidenceIntervalButtonGroup  matlab.ui.container.ButtonGroup
        BSButton                       matlab.ui.control.ToggleButton
        DKWButton                      matlab.ui.control.ToggleButton
        GridLayout10                   matlab.ui.container.GridLayout
        BootsSpinnerLabel              matlab.ui.control.Label
        BootsSpinner                   matlab.ui.control.Spinner
        StopDateDatePickerLabel        matlab.ui.control.Label
        StopDateDatePicker             matlab.ui.control.DatePicker
        ListCountryCodesTab            matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        Panel                          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        UpdateDatabaseButton           matlab.ui.control.Button
        ModelButton                    matlab.ui.control.Button
        InfoButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        GridLayout12                   matlab.ui.container.GridLayout
        GridLayout13                   matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        GridLayout14                   matlab.ui.container.GridLayout
        LocaleLabel                    matlab.ui.control.Label
        FPLabel                        matlab.ui.control.Label
        LPLabel                        matlab.ui.control.Label
        PhaseLabel                     matlab.ui.control.Label
        LocaleLabelText                matlab.ui.control.Label
        FPLabelText                    matlab.ui.control.Label
        LPLabelText                    matlab.ui.control.Label
        PhaseLabelText                 matlab.ui.control.Label
        MetricsPanel                   matlab.ui.container.Panel
        DateDatePickerLabel            matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        YIREditFieldLabel              matlab.ui.control.Label
        YIREditField                   matlab.ui.control.EditField
        XIREditFieldLabel              matlab.ui.control.Label
        XIREditField                   matlab.ui.control.EditField
        R2GoFEditFieldLabel            matlab.ui.control.Label
        R2GoFEditField                 matlab.ui.control.EditField
        KSGoFEditFieldLabel            matlab.ui.control.Label
        KSGoFEditField                 matlab.ui.control.EditField
        KSdistEditFieldLabel           matlab.ui.control.Label
        KSdistEditField                matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % discrete-time variable
        time_data
        % country-codes list
        codes
        % country-codes and name table
        tcodes
        % bnds of tcodes
        xbnds
        % manual or actual end of tcode
        gend
        % modelled first data-point time
        fptime;
        % modelled last data-point time
        lptime
        % country-code possible phase
        ccphase
        % logic if model ran
        modelran
        % logic if model updated
        dataupdated
        % userfolder path
        userfp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~(ismcc || isdeployed)
                [this_filepath,~,~]= fileparts(mfilename('fullpath'));
                rootpath = strrep(this_filepath, [filesep 'applet'], '');
                app.userfp = cd(rootpath);
                addpath(genpath(rootpath));
                if isfolder(fullfile(rootpath,'bin'))
                    rmpath(fullfile(rootpath,"bin"))
                end
                cd(app.userfp);
                warning('off','MATLAB:subscripting:noSubscriptsSpecified');
            end
            % Searchcodes
            app.tcodes = get_cc(app);
            app.xbnds = time_histbnds("WD",app);
            app.codes = app.tcodes{:,1};
            app.codes(end+1,1) = {'WD'};
            app.SearchCodesDropDown.Items = app.codes;
            app.SearchCodesDropDown.FontName = 'Consolas';
            app.SearchCodesDropDown.Value = app.codes(end);
            
            % Tables
            app.UITable.ColumnName = {'Codes'; 'Country'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = false;
            app.UITable.FontName = 'Consolas';
            app.UITable.FontSize = 10;
            app.UITable.Data = app.tcodes;
            
            
            % boots
            
            app.FinerCheckBox.Value = true;
            app.FinerCheckBox.Enable = false;
            FinerCheckBoxValueChanged(app);
            
            app.BootsSpinner.Value = 70;
            %             app.BSButton.Value = false;
            %             app.DKWButton.Value = true;
            ConfidenceIntervalButtonGroupSelectionChanged(app);
            
            % time or date
            app.DateDatePicker.Enable = 0;
            app.gend = app.xbnds.end;
            app.StopDateDatePicker.Limits = [app.xbnds.begin+14 app.xbnds.end];
            app.StopDateDatePicker.Value = app.xbnds.end;

            ccname = "World";
            app.LocaleLabelText.Text = ccname;
            app.FPLabelText.Text = string(app.xbnds.begin);
            app.LPLabelText.Text = string(app.xbnds.end);
            app.PhaseLabelText.Text = "";
            
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            app.ModelButton.Enable = 0;
            app.DateDatePicker.Enable = 0;
            app.SearchCodesDropDown.Enable = 0;
            app.UpdateDropDown.Enable = 0;
            app.InfectionsButton.Enable = 0;
            app.DeathsButton.Enable = 0;
            app.StopDateDatePicker.Enable = 0;
            app.UpdateDatabaseButton.Enable = 0;
            app.BSButton.Enable = 0;
            app.DKWButton.Enable = 0;
            app.FinerCheckBox.Enable = 0;
            app.InfoButton.Enable = 0;
            
            
            search_code = string(app.SearchCodesDropDown.Value);
            nboot = app.BootsSpinner.Value;
            if strcmp(app.UpdateDropDown.Value,'off')
                update = 0;
            else
                update = 1;
            end
            if app.InfectionsButton.Value == true
                focus = 'i';
            else
                focus = 'd';
            end
            
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            app.UIAxes.Box = true;
            app.UIAxes.BoxStyle = 'full';
            app.UIAxes.FontSize = 10;
            app.UIAxes.FontName = 'Corbel';
            app.UIAxes2.Box = true;            
            app.UIAxes2.FontSize = 10;
            app.UIAxes2.BoxStyle = 'full';
            app.UIAxes2.FontName = 'Corbel';
            
            
            app.BootsSpinner.Enable=0;
            value = app.StopDateDatePicker.Value;
            %disp(value);
            app.xbnds.end = datetime(value);
            
            %if any(isdatetime(app.time_data))
                %tmsg = " \fontsize{10} \fontname{Consolas}"+search_code+": \color[rgb]{0.2 0.5 0.9} "...
                %    +string(app.time_data(1))+" \fontname{Arial}to \fontname{Consolas} "...
                %    +string(app.time_data(end));
                %app.Panel2.Title = tmsg;
                %title(app.UIAxes,tmsg);
                %title(app.UIAxes2,tmsg);
            %end
            
            finer = app.FinerCheckBox.Value;
            boots = app.BSButton.Value;
            
            try
                [app, app.time_data, ~, fitstats,ymets,xmets,~] = ...
                    cov19_nlsigquery_applet(search_code,update, ...
                    focus,nboot, boots,finer, app);
                
                app.DateDatePicker.Limits = [app.time_data(1) app.time_data(end)];
                app.DateDatePicker.Value = app.time_data(end);
                app.ModelButton.Enable = 1;
                app.DateDatePicker.Enable = 1;
%                 app.BootsSpinner.Enable=1;
                app.SearchCodesDropDown.Enable = 1;
                app.UpdateDropDown.Enable = 1;
                app.InfectionsButton.Enable = 1;
                app.DeathsButton.Enable = 1;
                app.StopDateDatePicker.Enable = 1;
                app.UpdateDatabaseButton.Enable = 1;
                app.InfoButton.Enable = 1;
                
                %                 app.BSButton.Enable = 1;
                %                 app.DKWButton.Enable = 1;
%                 app.FinerCheckBox.Enable = 1;
%                 FinerCheckBoxValueChanged(app);
                
                app.StopDateDatePicker.Limits = [app.xbnds.begin app.gend];
                %app.StopDateDatePicker.Value = app.gend;
                
                app.R2GoFEditField.Value = sprintf("%.3g\n", fitstats.R2a);
                app.YIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    median(ymets(end,:)),min(ymets(end,:)),max(ymets(end,:)));
                app.XIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    median(xmets(end,:)),min(xmets(end,:)),max(xmets(end,:)));
                
                app.KSGoFEditField.Value = sprintf("%.3g\n", fitstats.KSgof);
                app.KSdistEditField.Value = sprintf("%.3g\n", fitstats.normDc);
                
                app.FPLabelText.Text = string(app.fptime);
                app.LPLabelText.Text = string(app.lptime);
                app.PhaseLabelText.Text = app.ccphase;
                
                app.modelran = 1;
            catch ME
                app.ModelButton.Enable = 1;
                rethrow(ME);
            end
            
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            if app.modelran == 1
                time_query = app.DateDatePicker.Value;
                query_ccode = string(app.SearchCodesDropDown.Value);
                if app.InfectionsButton.Value == true
                    focus = 'i';
                else
                    focus = 'd';
                end
                
                
                row_mets = get_metrics(query_ccode, time_query,...
                    focus, app.time_data);
                
                app.R2GoFEditField.Value = sprintf("%.3g\n", row_mets(1));
                app.YIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    row_mets(5),row_mets(6),row_mets(7));
                app.XIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    row_mets(2),row_mets(3),row_mets(4));
            end
        end

        % Value changed function: BootsSpinner
        function BootsSpinnerValueChanged(app, event)
            nboot = app.BootsSpinner.Value; %#ok<NASGU>
            %depreceated
        end

        % Button pushed function: UpdateDatabaseButton
        function UpdateDatabaseButtonPushed(app, event)
            app.InfoButton.Enable = 0;
            app.ModelButton.Enable = 0;
            upd_cdata_applet("ALL",1,app);
            app.InfoButton.Enable = 1;
            app.ModelButton.Enable = 1;
            app.dataupdated = 1;
        end

        % Selection changed function: ConfidenceIntervalButtonGroup
        function ConfidenceIntervalButtonGroupSelectionChanged(app, event)
            
            if app.BSButton.Value ~= true
                app.BootsSpinner.Enable = false;
            else
                app.BootsSpinner.Enable = true;
            end
            
        end

        % Value changed function: SearchCodesDropDown
        function SearchCodesDropDownValueChanged(app, event)
            value = app.SearchCodesDropDown.Value;
            %disp(value);
            t = time_histbnds(string(value),app);
            %t = datetime(t);
            app.xbnds.begin = t.begin+14;
            app.xbnds.end = t.end;
            app.gend = app.xbnds.end;
            app.StopDateDatePicker.Limits = [app.xbnds.begin app.xbnds.end];
            app.StopDateDatePicker.Value = app.xbnds.end;
            
            if strcmp(string(value),"WD")
                ccname = "World";
            else
                cid = (app.tcodes{:,1}==string(value));
                ccname = app.tcodes{cid,2};
            end
            app.LocaleLabelText.Text = ccname;
            app.FPLabelText.Text = string(app.xbnds.begin);
            app.LPLabelText.Text = string(app.xbnds.end);
            app.PhaseLabelText.Text = "";
        end

        % Value changed function: StopDateDatePicker
        function StopDateDatePickerValueChanged(app, event)
            value = app.StopDateDatePicker.Value;
            app.xbnds.end = datetime(value);
            app.LPLabelText.Text = string(app.xbnds.end);
            app.PhaseLabelText.Text = "";
        end

        % Value changed function: FinerCheckBox
        function FinerCheckBoxValueChanged(app, event)
            value = app.FinerCheckBox.Value;
            if value == true
                app.DKWButton.Value = true;
                app.DKWButton.Enable = false;
                app.BSButton.Value = false;
                app.BSButton.Enable = false;
            else
                app.DKWButton.Value = true;
                app.DKWButton.Enable = true;
                app.BSButton.Value = false;
                app.BSButton.Enable = true;
            end
            ConfidenceIntervalButtonGroupSelectionChanged(app);
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            infofig = app.NLSIGCOVID19LABAPPUIFigure;
            
            message = sprintf(['A Descriptive Modelling Playground or Lab\n' ...
                'Data: COVID-19 Infections and Deaths\n' ...
                'ModelFunction: NLOGISTIC-SIGMOID (NLSIG) function.\n' ...
                'Author: Oluwasegun Somefun (oasomefun@futa.edu.ng)']);
            
            uialert(infofig,message,'About',...
                'Icon','info');
        end

        % Close request function: NLSIGCOVID19LABAPPUIFigure
        function NLSIGCOVID19LABAPPUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NLSIGCOVID19LABAPPUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {267, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLSIGCOVID19LABAPPUIFigure = uifigure('Visible', 'off');
            app.NLSIGCOVID19LABAPPUIFigure.Name = 'NLSIG COVID-19LAB APP';
            app.NLSIGCOVID19LABAPPUIFigure.Icon = 'plot_24.png';
            app.NLSIGCOVID19LABAPPUIFigure.CloseRequestFcn = createCallbackFcn(app, @NLSIGCOVID19LABAPPUIFigureCloseRequest, true);
            app.NLSIGCOVID19LABAPPUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NLSIGCOVID19LABAPPUIFigure.Tag = 'nlsigcv19labflow';
            app.GridLayout = uigridlayout(app.NLSIGCOVID19LABAPPUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ConsolePanel = uipanel(app.GridLayout2);
            app.ConsolePanel.Title = 'Console';
            app.GridLayout3 = uigridlayout(app.ConsolePanel);
            app.StatusLabel = uilabel(app.GridLayout3);
            app.StatusLabel.Text = 'Status';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayout6 = uigridlayout(app.OptionsTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.SearchCodesDropDown = uidropdown(app.GridLayout7);
            app.SearchCodesDropDown.Items = {'NG', 'US', 'WD'};
            app.SearchCodesDropDown.Editable = 'on';
            app.SearchCodesDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchCodesDropDownValueChanged, true);
            app.SearchCodesDropDown.Value = 'WD';
            app.SearchCodesDropDownLabel = uilabel(app.GridLayout7);
            app.SearchCodesDropDownLabel.Text = 'Search Codes';
            app.EpidemicGroupButtonGroup = uibuttongroup(app.GridLayout6);
            app.EpidemicGroupButtonGroup.Title = 'Epidemic Group';
            app.InfectionsButton = uiradiobutton(app.EpidemicGroupButtonGroup);
            app.InfectionsButton.Text = 'Infections';
            app.InfectionsButton.Value = true;
            app.DeathsButton = uiradiobutton(app.EpidemicGroupButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.UpdateDropDownLabel = uilabel(app.GridLayout8);
            app.UpdateDropDownLabel.Text = 'Update';
            app.UpdateDropDown = uidropdown(app.GridLayout8);
            app.UpdateDropDown.Items = {'off', 'on'};
            app.UpdateDropDown.Tooltip = {'off'; 'on'};
            app.UpdateDropDown.Value = 'off';
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.FinerCheckBox = uicheckbox(app.GridLayout9);
            app.FinerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FinerCheckBoxValueChanged, true);
            app.FinerCheckBox.Text = 'Finer';
            app.FinerCheckBox.Value = true;
            app.ConfidenceIntervalButtonGroup = uibuttongroup(app.GridLayout9);
            app.ConfidenceIntervalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfidenceIntervalButtonGroupSelectionChanged, true);
            app.ConfidenceIntervalButtonGroup.Title = 'Confidence Interval';
            app.BSButton = uitogglebutton(app.ConfidenceIntervalButtonGroup);
            app.BSButton.Text = 'BS';
            app.DKWButton = uitogglebutton(app.ConfidenceIntervalButtonGroup);
            app.DKWButton.Text = 'DKW';
            app.DKWButton.Value = true;
            app.GridLayout10 = uigridlayout(app.GridLayout6);
            app.BootsSpinnerLabel = uilabel(app.GridLayout10);
            app.BootsSpinnerLabel.Text = 'Boots';
            app.BootsSpinner = uispinner(app.GridLayout10);
            app.BootsSpinner.Step = 10;
            app.BootsSpinner.Limits = [2 1000];
            app.BootsSpinner.RoundFractionalValues = 'on';
            app.BootsSpinner.ValueChangedFcn = createCallbackFcn(app, @BootsSpinnerValueChanged, true);
            app.BootsSpinner.Value = 70;
            app.StopDateDatePickerLabel = uilabel(app.GridLayout10);
            app.StopDateDatePickerLabel.Text = 'Stop-Date';
            app.StopDateDatePicker = uidatepicker(app.GridLayout10);
            app.StopDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StopDateDatePickerValueChanged, true);
            app.StopDateDatePicker.Value = datetime([2020 1 1]);
            app.ListCountryCodesTab = uitab(app.TabGroup);
            app.ListCountryCodesTab.Title = 'List:Country-Codes';
            app.GridLayout4 = uigridlayout(app.ListCountryCodesTab);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.UpdateDatabaseButton = uibutton(app.GridLayout5, 'push');
            app.UpdateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDatabaseButtonPushed, true);
            app.UpdateDatabaseButton.Tag = 'UpdateDatabase';
            app.UpdateDatabaseButton.Icon = 'import_24.png';
            app.UpdateDatabaseButton.Tooltip = {'Update Database'};
            app.UpdateDatabaseButton.Text = '';
            app.ModelButton = uibutton(app.GridLayout5, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Tag = 'Model';
            app.ModelButton.Icon = 'play_24.png';
            app.ModelButton.Tooltip = {'Model'};
            app.ModelButton.Text = '';
            app.InfoButton = uibutton(app.GridLayout5, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Icon = 'info_16.png';
            app.InfoButton.Tooltip = {'View Country-codes'};
            app.InfoButton.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.RightPanel);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.UIAxes = uiaxes(app.GridLayout13);
            app.UIAxes2 = uiaxes(app.GridLayout13);
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.LocaleLabel = uilabel(app.GridLayout14);
            app.LocaleLabel.Text = 'Locale';
            app.FPLabel = uilabel(app.GridLayout14);
            app.FPLabel.Tooltip = {'First Date'};
            app.FPLabel.Text = 'F.P';
            app.LPLabel = uilabel(app.GridLayout14);
            app.LPLabel.Tooltip = {'Last Picked Date'};
            app.LPLabel.Text = 'L.P';
            app.PhaseLabel = uilabel(app.GridLayout14);
            app.PhaseLabel.Tooltip = {'Possible Phase'};
            app.PhaseLabel.Text = 'Phase';
            app.LocaleLabelText = uilabel(app.GridLayout14);
            app.LocaleLabelText.Text = 'World';
            app.FPLabelText = uilabel(app.GridLayout14);
            app.FPLabelText.Text = '15-01-2020';
            app.LPLabelText = uilabel(app.GridLayout14);
            app.LPLabelText.Text = '15-01-2021';
            app.PhaseLabelText = uilabel(app.GridLayout14);
            app.PhaseLabelText.Text = 'peak';
            app.MetricsPanel = uipanel(app.GridLayout11);
            app.MetricsPanel.Title = 'Metrics';
            app.DateDatePickerLabel = uilabel(app.MetricsPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.MetricsPanel);
            app.DateDatePicker.Editable = 'off';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Value = datetime([2020 1 1]);
            app.YIREditFieldLabel = uilabel(app.MetricsPanel);
            app.YIREditFieldLabel.Text = 'YIR';
            app.YIREditField = uieditfield(app.MetricsPanel, 'text');
            app.YIREditField.Editable = 'off';
            app.XIREditFieldLabel = uilabel(app.MetricsPanel);
            app.XIREditFieldLabel.Text = 'XIR';
            app.XIREditField = uieditfield(app.MetricsPanel, 'text');
            app.XIREditField.Editable = 'off';
            app.R2GoFEditFieldLabel = uilabel(app.MetricsPanel);
            app.R2GoFEditFieldLabel.Text = 'R2GoF';
            app.R2GoFEditField = uieditfield(app.MetricsPanel, 'text');
            app.R2GoFEditField.Editable = 'off';
            app.KSGoFEditFieldLabel = uilabel(app.MetricsPanel);
            app.KSGoFEditFieldLabel.Text = 'KSGoF';
            app.KSGoFEditField = uieditfield(app.MetricsPanel, 'text');
            app.KSGoFEditField.Editable = 'off';
            app.KSdistEditFieldLabel = uilabel(app.MetricsPanel);
            app.KSdistEditFieldLabel.Text = 'KSdist';
            app.KSdistEditField = uieditfield(app.MetricsPanel, 'text');
            app.KSdistEditField.Editable = 'off';
            % Show the figure after all components are created
            app.NLSIGCOVID19LABAPPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid19nlsigApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLSIGCOVID19LABAPPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLSIGCOVID19LABAPPUIFigure)
        end
    end
end