classdef nlsigcovid19lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLSIGCOVID19LABUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        OptionsTab                     matlab.ui.container.Tab
        ModelButton                    matlab.ui.control.Button
        SearchCodesDropDownLabel       matlab.ui.control.Label
        SearchCodesDropDown            matlab.ui.control.DropDown
        UpdateDropDownLabel            matlab.ui.control.Label
        UpdateDropDown                 matlab.ui.control.DropDown
        BootsSpinnerLabel              matlab.ui.control.Label
        BootsSpinner                   matlab.ui.control.Spinner
        ConsolePanel                   matlab.ui.container.Panel
        StatusLabel                    matlab.ui.control.Label
        ConfidenceIntervalButtonGroup  matlab.ui.container.ButtonGroup
        BSButton                       matlab.ui.control.ToggleButton
        DKWButton                      matlab.ui.control.ToggleButton
        EpidemicGroupButtonGroup       matlab.ui.container.ButtonGroup
        InfectionsButton               matlab.ui.control.RadioButton
        DeathsButton                   matlab.ui.control.RadioButton
        UpdateDatabaseButton           matlab.ui.control.Button
        EndDatePickerLabel             matlab.ui.control.Label
        EndDatePicker                  matlab.ui.control.DatePicker
        FinerCheckBox                  matlab.ui.control.CheckBox
        CountryCodeListTab             matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        CenterPanel                    matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        CummulativeTab                 matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        IncidentTab                    matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        MetricsPanel                   matlab.ui.container.Panel
        TimeDatePickerLabel            matlab.ui.control.Label
        TimeDatePicker                 matlab.ui.control.DatePicker
        YIREditFieldLabel              matlab.ui.control.Label
        YIREditField                   matlab.ui.control.EditField
        XIREditFieldLabel              matlab.ui.control.Label
        XIREditField                   matlab.ui.control.EditField
        GoFEditFieldLabel              matlab.ui.control.Label
        GoFEditField                   matlab.ui.control.EditField
        DeEditFieldLabel               matlab.ui.control.Label
        DeEditField                    matlab.ui.control.EditField
        DcEditFieldLabel               matlab.ui.control.Label
        DcEditField                    matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        time_data % Description
        codes % describe
        tcodes
        xbnds
        gend
        modelran
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                       
            if ~(ismcc || isdeployed)
                [this_filepath,~,~]= fileparts(mfilename('fullpath'));
                rootpath = strrep(this_filepath, [filesep 'applet'], '');
                cd(rootpath);
                addpath(genpath(rootpath));
                if isfolder(fullfile(rootpath,'bin'))
                    rmpath(fullfile(rootpath,"bin"))
                end
            end
            % Searchcodes
            [app.xbnds, app.tcodes] = allcc_view_applet(app);
            app.codes = app.tcodes{:,1};
            app.codes(end+1,1) = {'WD'};
            app.SearchCodesDropDown.Items = app.codes;
            app.SearchCodesDropDown.FontName = 'Consolas';
            app.SearchCodesDropDown.Value = app.codes(end);
            
            % Tables
            app.UITable.ColumnName = {'Codes'; 'Country'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = false;
            app.UITable.FontName = 'Consolas';
            app.UITable.FontSize = 10;
            app.UITable.Data = app.tcodes;
        
        
            % boots
            
            app.FinerCheckBox.Value = true;
            FinerCheckBoxValueChanged(app);
            
            app.BootsSpinner.Value = 70;             
%             app.BSButton.Value = false;
%             app.DKWButton.Value = true;
            ConfidenceIntervalButtonGroupSelectionChanged(app);
            
            % time or date
            app.TimeDatePicker.Enable = 0;
            app.gend = app.xbnds.end;
            app.EndDatePicker.Limits = [app.xbnds.begin+14 app.xbnds.end];
            app.EndDatePicker.Value = app.xbnds.end;


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NLSIGCOVID19LABUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {424, 424, 424};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {424, 424};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            app.ModelButton.Enable = 0;
            app.TimeDatePicker.Enable = 0;
            app.SearchCodesDropDown.Enable = 0;
            app.UpdateDropDown.Enable = 0;
            app.InfectionsButton.Enable = 0;
            app.DeathsButton.Enable = 0;
            app.EndDatePicker.Enable = 0;
            app.UpdateDatabaseButton.Enable = 0;
            app.BSButton.Enable = 0;
            app.DKWButton.Enable = 0;
            app.FinerCheckBox.Enable = 0;
            
            
            search_code = string(app.SearchCodesDropDown.Value);
            nboot = app.BootsSpinner.Value;
            if strcmp(app.UpdateDropDown.Value,'off')
                update = 0;
            else
                update = 1;
            end
            if app.InfectionsButton.Value == true
                focus = 'i';
            else
                focus = 'd';
            end
            
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            
            app.BootsSpinner.Enable=0;
            value = app.EndDatePicker.Value;
            app.xbnds.end = datetime(value);  

            if any(isdatetime(app.time_data))
                tmsg = " \fontsize{10} \fontname{Consolas}"+search_code+": \color[rgb]{0.2 0.5 0.9} "...
                    +string(app.time_data(1))+" \fontname{Arial}to \fontname{Consolas} "...
                    +string(app.time_data(end));
                title(app.UIAxes,tmsg);
                title(app.UIAxes2,tmsg);
            end
            
            finer = app.FinerCheckBox.Value;
            boots = app.BSButton.Value;
            
            try
                [app, app.time_data,fitstats,ymets,xmets,~] = ...
                    cov19_nlsigquery_applet(search_code,update, ...
                    focus,nboot, boots,finer, app);
                
                app.TimeDatePicker.Limits = [app.time_data(1) app.time_data(end)];
                app.TimeDatePicker.Value = app.time_data(end);
                app.ModelButton.Enable = 1;
                app.TimeDatePicker.Enable = 1;
                app.BootsSpinner.Enable=1;
                app.SearchCodesDropDown.Enable = 1;
                app.UpdateDropDown.Enable = 1;
                app.InfectionsButton.Enable = 1;
                app.DeathsButton.Enable = 1;
                app.EndDatePicker.Enable = 1;
                app.UpdateDatabaseButton.Enable = 1;
                
%                 app.BSButton.Enable = 1;
%                 app.DKWButton.Enable = 1;
                app.FinerCheckBox.Enable = 1;
                FinerCheckBoxValueChanged(app);
                
                app.EndDatePicker.Limits = [app.xbnds.begin app.gend];
                %app.EndDatePicker.Value = app.gend;
                
                app.GoFEditField.Value = sprintf("%.3g\n", fitstats.R2a);
                app.YIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    median(ymets(end,:)),min(ymets(end,:)),max(ymets(end,:)));
                app.XIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    median(xmets(end,:)),min(xmets(end,:)),max(xmets(end,:)));
    
                app.DeEditField.Value = sprintf("%.3g\n", fitstats.valEe);
                app.DcEditField.Value = sprintf("%.3g\n", fitstats.valEi);
                
                app.modelran = 1;
            catch ME
                app.ModelButton.Enable = 1;
                rethrow(ME);
            end
        end

        % Value changed function: TimeDatePicker
        function TimeDatePickerValueChanged(app, event)
            if app.modelran == 1
                time_query = app.TimeDatePicker.Value;
                query_ccode = string(app.SearchCodesDropDown.Value);
                if app.InfectionsButton.Value == true
                    focus = 'i';
                else
                    focus = 'd';
                end
                
                row_mets = get_metrics(query_ccode, time_query,...
                    focus, app.time_data);
                
                app.GoFEditField.Value = sprintf("%.3g\n", row_mets(1));  
                app.YIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    row_mets(5),row_mets(6),row_mets(7));
                app.XIREditField.Value = sprintf("%.3g [%.3g, %.3g]\n",...
                    row_mets(2),row_mets(3),row_mets(4));
            end
        end

        % Value changed function: BootsSpinner
        function BootsSpinnerValueChanged(app, event)
            nboot = app.BootsSpinner.Value; %#ok<NASGU> 
        end

        % Button pushed function: UpdateDatabaseButton
        function UpdateDatabaseButtonPushed(app, event)
            allcc_upd_applet(app);
        end

        % Selection changed function: ConfidenceIntervalButtonGroup
        function ConfidenceIntervalButtonGroupSelectionChanged(app, event)

            if app.BSButton.Value ~= true
                app.BootsSpinner.Enable = false;
            else
                app.BootsSpinner.Enable = true;
            end
            
        end

        % Value changed function: SearchCodesDropDown
        function SearchCodesDropDownValueChanged(app, event)
            value = app.SearchCodesDropDown.Value;
            t = getcasesbycc_applet(app,string(value),0,'o');
            t = datetime(t);
            app.xbnds.begin = t(1)+14;
            app.xbnds.end = t(end);
            app.gend = app.xbnds.end;
            app.EndDatePicker.Limits = [app.xbnds.begin app.xbnds.end];
            app.EndDatePicker.Value = app.xbnds.end;
        end

        % Value changed function: EndDatePicker
        function EndDatePickerValueChanged(app, event)
            value = app.EndDatePicker.Value;
            app.xbnds.end = datetime(value);        
        end

        % Value changed function: FinerCheckBox
        function FinerCheckBoxValueChanged(app, event)
            value = app.FinerCheckBox.Value;
            if value == true
                app.DKWButton.Value = true;
                app.DKWButton.Enable = false;
                app.BSButton.Value = false;
                app.BSButton.Enable = false;         
            else
                app.DKWButton.Value = true;
                app.DKWButton.Enable = true;
                app.BSButton.Value = false;
                app.BSButton.Enable = true;
            end
            ConfidenceIntervalButtonGroupSelectionChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLSIGCOVID19LABUIFigure = uifigure('Visible', 'off');
            app.NLSIGCOVID19LABUIFigure.Name = 'NLSIG COVID-19 LAB';
            app.NLSIGCOVID19LABUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NLSIGCOVID19LABUIFigure.Tag = 'nlsigcv19lab';
            app.GridLayout = uigridlayout(app.NLSIGCOVID19LABUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'COVID-19: NLSIG MODELLING ';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.OptionsTab = uitab(app.TabGroup2);
            app.OptionsTab.Title = 'Options';
            app.ModelButton = uibutton(app.OptionsTab, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Tooltip = {'run'};
            app.ModelButton.Text = 'Model';
            app.SearchCodesDropDownLabel = uilabel(app.OptionsTab);
            app.SearchCodesDropDownLabel.Text = 'Search Codes';
            app.SearchCodesDropDown = uidropdown(app.OptionsTab);
            app.SearchCodesDropDown.Items = {'NG', 'US', 'WD'};
            app.SearchCodesDropDown.Editable = 'on';
            app.SearchCodesDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchCodesDropDownValueChanged, true);
            app.SearchCodesDropDown.Value = 'WD';
            app.UpdateDropDownLabel = uilabel(app.OptionsTab);
            app.UpdateDropDownLabel.Text = 'Update';
            app.UpdateDropDown = uidropdown(app.OptionsTab);
            app.UpdateDropDown.Items = {'off', 'on'};
            app.UpdateDropDown.Tooltip = {'off'; 'on'};
            app.UpdateDropDown.Value = 'off';
            app.BootsSpinnerLabel = uilabel(app.OptionsTab);
            app.BootsSpinnerLabel.Text = 'Boots';
            app.BootsSpinner = uispinner(app.OptionsTab);
            app.BootsSpinner.Step = 10;
            app.BootsSpinner.Limits = [2 1000];
            app.BootsSpinner.RoundFractionalValues = 'on';
            app.BootsSpinner.ValueChangedFcn = createCallbackFcn(app, @BootsSpinnerValueChanged, true);
            app.BootsSpinner.Value = 70;
            app.ConsolePanel = uipanel(app.OptionsTab);
            app.ConsolePanel.Title = 'Console';
            app.StatusLabel = uilabel(app.ConsolePanel);
            app.StatusLabel.Text = 'Status';
            app.ConfidenceIntervalButtonGroup = uibuttongroup(app.OptionsTab);
            app.ConfidenceIntervalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfidenceIntervalButtonGroupSelectionChanged, true);
            app.ConfidenceIntervalButtonGroup.Title = 'Confidence Interval';
            app.BSButton = uitogglebutton(app.ConfidenceIntervalButtonGroup);
            app.BSButton.Text = 'BS';
            app.DKWButton = uitogglebutton(app.ConfidenceIntervalButtonGroup);
            app.DKWButton.Text = 'DKW';
            app.DKWButton.Value = true;
            app.EpidemicGroupButtonGroup = uibuttongroup(app.OptionsTab);
            app.EpidemicGroupButtonGroup.Title = 'Epidemic Group';
            app.InfectionsButton = uiradiobutton(app.EpidemicGroupButtonGroup);
            app.InfectionsButton.Text = 'Infections';
            app.InfectionsButton.Value = true;
            app.DeathsButton = uiradiobutton(app.EpidemicGroupButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.UpdateDatabaseButton = uibutton(app.OptionsTab, 'push');
            app.UpdateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDatabaseButtonPushed, true);
            app.UpdateDatabaseButton.Text = 'Update Database';
            app.EndDatePickerLabel = uilabel(app.OptionsTab);
            app.EndDatePickerLabel.Text = 'End';
            app.EndDatePicker = uidatepicker(app.OptionsTab);
            app.EndDatePicker.Editable = 'off';
            app.EndDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDatePickerValueChanged, true);
            app.EndDatePicker.Value = datetime([2020 1 1]);
            app.FinerCheckBox = uicheckbox(app.OptionsTab);
            app.FinerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FinerCheckBoxValueChanged, true);
            app.FinerCheckBox.Text = 'Finer';
            app.FinerCheckBox.Value = true;
            app.CountryCodeListTab = uitab(app.TabGroup2);
            app.CountryCodeListTab.Title = 'Country Code List';
            app.UITable = uitable(app.CountryCodeListTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.CummulativeTab = uitab(app.TabGroup);
            app.CummulativeTab.Title = 'Cummulative';
            app.UIAxes = uiaxes(app.CummulativeTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.IncidentTab = uitab(app.TabGroup);
            app.IncidentTab.Title = 'Incident';
            app.UIAxes2 = uiaxes(app.IncidentTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.MetricsPanel = uipanel(app.CenterPanel);
            app.MetricsPanel.Title = 'Metrics';
            app.TimeDatePickerLabel = uilabel(app.MetricsPanel);
            app.TimeDatePickerLabel.Text = 'Time';
            app.TimeDatePicker = uidatepicker(app.MetricsPanel);
            app.TimeDatePicker.Editable = 'off';
            app.TimeDatePicker.ValueChangedFcn = createCallbackFcn(app, @TimeDatePickerValueChanged, true);
            app.TimeDatePicker.Value = datetime([2020 1 1]);
            app.YIREditFieldLabel = uilabel(app.MetricsPanel);
            app.YIREditFieldLabel.Text = 'YIR';
            app.YIREditField = uieditfield(app.MetricsPanel, 'text');
            app.YIREditField.Editable = 'off';
            app.XIREditFieldLabel = uilabel(app.MetricsPanel);
            app.XIREditFieldLabel.Text = 'XIR';
            app.XIREditField = uieditfield(app.MetricsPanel, 'text');
            app.XIREditField.Editable = 'off';
            app.GoFEditFieldLabel = uilabel(app.MetricsPanel);
            app.GoFEditFieldLabel.Text = 'GoF';
            app.GoFEditField = uieditfield(app.MetricsPanel, 'text');
            app.GoFEditField.Editable = 'off';
            app.DeEditFieldLabel = uilabel(app.MetricsPanel);
            app.DeEditFieldLabel.Text = 'De';
            app.DeEditField = uieditfield(app.MetricsPanel, 'text');
            app.DeEditField.Editable = 'off';
            app.DcEditFieldLabel = uilabel(app.MetricsPanel);
            app.DcEditFieldLabel.Text = 'Dc';
            app.DcEditField = uieditfield(app.MetricsPanel, 'text');
            app.DcEditField.Editable = 'off';
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageSource = 'nlsig_avatar.png';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.NLSIGCOVID19LABUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nlsigcovid19lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLSIGCOVID19LABUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLSIGCOVID19LABUIFigure)
        end
    end
end